classdef calibGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationUIFigure             matlab.ui.Figure
        WitADummymirrorPanel            matlab.ui.container.Panel
        FirstStepLabel                  matlab.ui.control.Label
        AlignDUMMYAndrecordaPhaseReferenceButton  matlab.ui.control.Button
        WithTheDMPanel                  matlab.ui.container.Panel
        LoadaPhaseReferenceButton       matlab.ui.control.Button
        SecondStepLabel                 matlab.ui.control.Label
        AlignDMButton                   matlab.ui.control.Button
        OpenEnvironmentControlPannelButton  matlab.ui.control.Button
        SecondStepLabel_3               matlab.ui.control.Label
        CheckDefaultInteractionMatrixButton  matlab.ui.control.Button
        CheckDefaultMatrixResult        matlab.ui.control.Label
        SecondStepLabel_4               matlab.ui.control.Label
        MakenewInteractionMatrixButton  matlab.ui.control.Button
        SecondStepLabel_5               matlab.ui.control.Label
        CheckNewMatrixButton            matlab.ui.control.Button
        MakeNewMatrixResult             matlab.ui.control.Label
        SecondStepLabel_6               matlab.ui.control.Label
        CreatePDFReportButton           matlab.ui.control.Button
        MakeNewMatrixResult_2           matlab.ui.control.Label
        WarmBenchatambienttemperatureButton  matlab.ui.control.Button
        PdfReportResult                 matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        SecondStepLabel_2               matlab.ui.control.Label
        SecondStepLabel_7               matlab.ui.control.Label
    end


    properties (Access = public)
        bench; % Description
        okColor = [0 1.0 0];
        notOkColor = [1.0 0.0 0.0];
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
           
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            app.bench = bench;
        end

        % Button pushed function: 
        % AlignDUMMYAndrecordaPhaseReferenceButton
        function AlignDUMMYAndrecordaPhaseReferenceButtonPushed(app, event)
            if isempty(naomi.findGui('Alignment'))
                alignmentGui(app.bench);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.CalibrationUIFigure);
        end

        % Button pushed function: LoadaPhaseReferenceButton
        function LoadaPhaseReferenceButtonPushed(app, event)
            naomi.config.phaseReference(app.bench);
        end

        % Button pushed function: AlignDMButton
        function AlignDMButtonPushed(app, event)
            naomi.task.align(app.bench);
        end

        % Button pushed function: OpenEnvironmentControlPannelButton
        function OpenEnvironmentControlPannelButtonPushed(app, event)
            naomi.task.controlEnvironmnet(app.bench);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationUIFigure = uifigure;
            app.CalibrationUIFigure.Name = 'Calibration';
            app.WitADummymirrorPanel = uipanel(app.CalibrationUIFigure);
            app.WitADummymirrorPanel.Title = 'Wit A Dummy mirror';
            app.FirstStepLabel = uilabel(app.WitADummymirrorPanel);
            app.FirstStepLabel.Text = '1. Take a bench wavefront reference  with the DUMMY mirror inside the bench [1 minute]';
            app.AlignDUMMYAndrecordaPhaseReferenceButton = uibutton(app.WitADummymirrorPanel, 'push');
            app.AlignDUMMYAndrecordaPhaseReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AlignDUMMYAndrecordaPhaseReferenceButtonPushed, true);
            app.AlignDUMMYAndrecordaPhaseReferenceButton.Text = 'Align DUMMY And record a Phase Reference';
            app.WithTheDMPanel = uipanel(app.CalibrationUIFigure);
            app.WithTheDMPanel.Title = 'With The DM';
            app.LoadaPhaseReferenceButton = uibutton(app.WithTheDMPanel, 'push');
            app.LoadaPhaseReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaPhaseReferenceButtonPushed, true);
            app.LoadaPhaseReferenceButton.Text = 'Load a Phase Reference';
            app.SecondStepLabel = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel.Text = '2. Align DM at ambient temperature [1minute]';
            app.AlignDMButton = uibutton(app.WithTheDMPanel, 'push');
            app.AlignDMButton.ButtonPushedFcn = createCallbackFcn(app, @AlignDMButtonPushed, true);
            app.AlignDMButton.Text = 'Align DM';
            app.OpenEnvironmentControlPannelButton = uibutton(app.WithTheDMPanel, 'push');
            app.OpenEnvironmentControlPannelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenEnvironmentControlPannelButtonPushed, true);
            app.OpenEnvironmentControlPannelButton.Text = 'Open Environment Control Pannel';
            app.SecondStepLabel_3 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_3.Text = '3. Check The Default Interaction Matrix [5 minutes] ';
            app.CheckDefaultInteractionMatrixButton = uibutton(app.WithTheDMPanel, 'push');
            app.CheckDefaultInteractionMatrixButton.Text = 'Check Default Interaction Matrix';
            app.CheckDefaultMatrixResult = uilabel(app.WithTheDMPanel);
            app.CheckDefaultMatrixResult.Text = '------- ';
            app.SecondStepLabel_4 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_4.Text = '4. Make a new interaction matrix and bias  [0.5 hours] ';
            app.MakenewInteractionMatrixButton = uibutton(app.WithTheDMPanel, 'push');
            app.MakenewInteractionMatrixButton.Text = 'Make new Interaction Matrix';
            app.SecondStepLabel_5 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_5.Text = '4. Check the new Interaction Matrix [5 minutes]';
            app.CheckNewMatrixButton = uibutton(app.WithTheDMPanel, 'push');
            app.CheckNewMatrixButton.Text = 'Check New Matrix';
            app.MakeNewMatrixResult = uilabel(app.WithTheDMPanel);
            app.MakeNewMatrixResult.Text = '------- ';
            app.SecondStepLabel_6 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_6.Text = '5. Warm up and Create PDF report';
            app.CreatePDFReportButton = uibutton(app.WithTheDMPanel, 'push');
            app.CreatePDFReportButton.Text = 'Create PDF Report';
            app.MakeNewMatrixResult_2 = uilabel(app.WithTheDMPanel);
            app.MakeNewMatrixResult_2.Text = '------- ';
            app.WarmBenchatambienttemperatureButton = uibutton(app.WithTheDMPanel, 'push');
            app.WarmBenchatambienttemperatureButton.Text = 'Warm Bench at ambient temperature';
            app.PdfReportResult = uilabel(app.WithTheDMPanel);
            app.PdfReportResult.Text = '------- ';
            app.CloseButton = uibutton(app.WithTheDMPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RefreshButton = uibutton(app.WithTheDMPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SecondStepLabel_2 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_2.Text = '3. Start Temperature Regulation (reach 10 degree)  [~1.5 hour if ambient at 20 degree] ';
            app.SecondStepLabel_7 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_7.Text = '1. Load a Measured DUMMY  Phase Reference';
        end
    end

    methods (Access = public)

        % Construct app
        function app = calibGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationUIFigure)
        end
    end
end