classdef environmentControlGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GoToCalibTemperatureButton  matlab.ui.control.Button
        MaintainTemperatureCalibModeButton  matlab.ui.control.Button
        TurnOffButton               matlab.ui.control.Button
        UIAxesHOT                   matlab.ui.control.UIAxes
        UIAxesCOLD                  matlab.ui.control.UIAxes
        UIAxesQSM                   matlab.ui.control.UIAxes
        HotValue                    matlab.ui.control.Label
        ColdValue                   matlab.ui.control.Label
        MirrorValue                 matlab.ui.control.Label
        QSMValue                    matlab.ui.control.Label
        UIAxesCURRENT               matlab.ui.control.UIAxes
        CurrentValue                matlab.ui.control.Label
        UIAxesFAN                   matlab.ui.control.UIAxes
        Fan1Value                   matlab.ui.control.Label
        Fan2Value                   matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
    end


    properties (Access = public)
        environment; % naomi.objects.Environment object
        buffer; % naomi.objects.EnvironmentBuffer object
        appTimer;
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            app.environment.updateControl();
            app.buffer.update(app.environment);
            
            if ~app.buffer.index
                return 
            end
            
            time = app.buffer.get('time');
            
            time = (time-time(1))*24*3600; % time in seconds 
            
            c = app.buffer.get('current');
            f1 = app.buffer.get('fan1');
            f2 = app.buffer.get('fan2');
            t1 = app.buffer.get('temp1');
            t2 = app.buffer.get('temp2');
            t5 = app.buffer.get('temp5');
            t6 = app.buffer.get('temp6');
            rt = app.buffer.get('regulTemp');
            plot(app.UIAxesCURRENT, time, c);
            
            plot(app.UIAxesFAN, time, f1);
            hold(app.UIAxesFAN, 'on');
            plot(app.UIAxesFAN, time, f2);
            hold(app.UIAxesFAN, 'off');
            
            plot(app.UIAxesHOT, time, t2);
            plot(app.UIAxesCOLD, time, t1);
            hold(app.UIAxesCOLD, 'on');
            plot(app.UIAxesCOLD, time, rt);
            hold(app.UIAxesCOLD, 'off');
            
            plot(app.UIAxesQSM, time, t5);
            hold(app.UIAxesQSM, 'on');
            plot(app.UIAxesQSM, time, t6);
            hold(app.UIAxesQSM, 'off');
            
            fmt = '%.2f';
            
            app.CurrentValue.Text = sprintf(fmt, c(end));
            app.Fan1Value.Text = sprintf(fmt, f1(end));
            app.Fan2Value.Text = sprintf(fmt, f2(end));
            app.HotValue.Text = sprintf(fmt, t2(end));
            app.ColdValue.Text = sprintf(fmt, t1(end));
            app.MirrorValue.Text = sprintf(fmt, t5(end));
            app.QSMValue.Text = sprintf(fmt, t6(end));
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, environment, buffer)
            if nargin<3
                buffer = environment.createBuffer(10000,2500,1);
            end
            app.environment = environment;
            app.buffer = buffer;
            app.appTimer = timer('Period',2.0,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.appTimer.TimerFcn = @app.refresh;
            
            start(app.appTimer);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Button pushed function: GoToCalibTemperatureButton
        function GoToCalibTemperatureButtonPushed(app, event)
            app.environment.goToCalibTemperature();
        end

        % Button pushed function: MaintainTemperatureCalibModeButton
        function MaintainTemperatureCalibModeButtonPushed(app, event)
           app.environment.setToCalib(); 
        end

        % Button pushed function: TurnOffButton
        function TurnOffButtonPushed(app, event)
           app.environment.turnOff();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.appTimer);
            delete(app.appTimer);
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GoToCalibTemperatureButton = uibutton(app.UIFigure, 'push');
            app.GoToCalibTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @GoToCalibTemperatureButtonPushed, true);
            app.GoToCalibTemperatureButton.Text = 'Go To Calib Temperature ';
            app.MaintainTemperatureCalibModeButton = uibutton(app.UIFigure, 'push');
            app.MaintainTemperatureCalibModeButton.ButtonPushedFcn = createCallbackFcn(app, @MaintainTemperatureCalibModeButtonPushed, true);
            app.MaintainTemperatureCalibModeButton.Text = 'Maintain Temperature  (Calib Mode)';
            app.TurnOffButton = uibutton(app.UIFigure, 'push');
            app.TurnOffButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffButtonPushed, true);
            app.TurnOffButton.Text = 'Turn Off';
            app.UIAxesHOT = uiaxes(app.UIFigure);
            title(app.UIAxesHOT, 'Peltier Hot Face')
            xlabel(app.UIAxesHOT, 'Time')
            ylabel(app.UIAxesHOT, 'T2')
            app.UIAxesCOLD = uiaxes(app.UIFigure);
            title(app.UIAxesCOLD, 'Peltier Cold Face')
            xlabel(app.UIAxesCOLD, 'Time')
            ylabel(app.UIAxesCOLD, 'T1')
            app.UIAxesQSM = uiaxes(app.UIFigure);
            title(app.UIAxesQSM, 'QSM')
            xlabel(app.UIAxesQSM, 'Time')
            ylabel(app.UIAxesQSM, 'T4+T5')
            app.HotValue = uilabel(app.UIFigure);
            app.HotValue.Text = '0.0';
            app.ColdValue = uilabel(app.UIFigure);
            app.ColdValue.Text = '0.0';
            app.MirrorValue = uilabel(app.UIFigure);
            app.MirrorValue.Text = '0.0';
            app.QSMValue = uilabel(app.UIFigure);
            app.QSMValue.Text = '0.0';
            app.UIAxesCURRENT = uiaxes(app.UIFigure);
            title(app.UIAxesCURRENT, 'Peltier Current')
            xlabel(app.UIAxesCURRENT, 'Time')
            ylabel(app.UIAxesCURRENT, 'current')
            app.CurrentValue = uilabel(app.UIFigure);
            app.CurrentValue.Text = '0.0';
            app.UIAxesFAN = uiaxes(app.UIFigure);
            title(app.UIAxesFAN, 'Fans')
            xlabel(app.UIAxesFAN, 'Time')
            ylabel(app.UIAxesFAN, 'Voltage')
            app.Fan1Value = uilabel(app.UIFigure);
            app.Fan1Value.Text = '0.0';
            app.Fan2Value = uilabel(app.UIFigure);
            app.Fan2Value.Text = '0.0';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = environmentControlGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end