classdef ifmExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIScreenPhase           matlab.ui.control.UIAxes
        UIMax                   matlab.ui.control.UIAxes
        UISign                  matlab.ui.control.UIAxes
        UIPos                   matlab.ui.control.UIAxes
        ActuatorEditFieldLabel  matlab.ui.control.Label
        ActuatorNumber          matlab.ui.control.NumericEditField
        NextActuator            matlab.ui.control.Button
        PreviousActuator        matlab.ui.control.Button
        UIHwhm                  matlab.ui.control.UIAxes
        UIXProfile              matlab.ui.control.UIAxes
        ProfileDropDownLabel    matlab.ui.control.Label
        ProfileDropDown         matlab.ui.control.DropDown
        CloseButton             matlab.ui.control.Button
        UIYProfile              matlab.ui.control.UIAxes
        UIResidual              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        IFMData; % The naomi.data.IFMatrix object
        curentActuatorNumber = 1;
    end

    methods (Access = public)
    
        function refresh(app)
            IFData = app.IFMData.IF(app.curentActuatorNumber);
            IFData.plotScreenPhase( app.UIScreenPhase);
            if isempty(app.IFMData.profileResult)
                cla(app.UIMax); cla(app.UISign); cla(app.UIHwhm);  cla(app.UIPos);
                title(app.UIMax, {'Fitting all profiles', 'can take some time'});
            end
            
            app.IFMData.plotQc(app.curentActuatorNumber, {app.UIMax, app.UISign, app.UIHwhm, app.UIPos});
            title(app.UIMax, '');
            xlabel(app.UIMax, '');
            xlabel(app.UISign, '');
            IFData.plotProfiles({app.UIXProfile, app.UIYProfile});
            
            
            IFData.plotProfile(app.UIResidual);
            [nActuator,~, ~] = size(app.IFMData.data);
            if app.curentActuatorNumber<=1
                app.PreviousActuator.Enable = 'off';
            else
                 app.PreviousActuator.Enable = 'on';
            end
            if app.curentActuatorNumber>=nActuator
                app.NextActuator.Enable = 'off';
            else
                 app.NextActuator.Enable = 'on';
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IFMData)
            app.IFMData = IFMData;
            [nActuator, ~, ~] = size(IFMData.data);
            app.ActuatorNumber.Limits = [1 nActuator];
            app.ProfileDropDown.Value = IFMData.fitType;
            app.refresh();
            
        end

        % Value changed function: ActuatorNumber
        function ActuatorNumberValueChanged(app, event)
            value = app.ActuatorNumber.Value;
            app.curentActuatorNumber = value;
            app.refresh();
        end

        % Button pushed function: NextActuator
        function NextActuatorButtonPushed(app, event)
            app.curentActuatorNumber = app.curentActuatorNumber + 1;
            app.ActuatorNumber.Value = app.curentActuatorNumber;
            app.refresh();
        end

        % Button pushed function: PreviousActuator
        function PreviousActuatorButtonPushed(app, event)
            app.curentActuatorNumber = app.curentActuatorNumber - 1;
            app.ActuatorNumber.Value = app.curentActuatorNumber;
            app.refresh();
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            app.IFMData.fitType = value;
            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIScreenPhase = uiaxes(app.UIFigure);
            app.UIMax = uiaxes(app.UIFigure);
            app.UISign = uiaxes(app.UIFigure);
            app.UIPos = uiaxes(app.UIFigure);
            app.ActuatorEditFieldLabel = uilabel(app.UIFigure);
            app.ActuatorEditFieldLabel.Text = 'Actuator';
            app.ActuatorNumber = uieditfield(app.UIFigure, 'numeric');
            app.ActuatorNumber.Limits = [1 9999];
            app.ActuatorNumber.ValueDisplayFormat = '%.0f';
            app.ActuatorNumber.ValueChangedFcn = createCallbackFcn(app, @ActuatorNumberValueChanged, true);
            app.ActuatorNumber.Value = 1;
            app.NextActuator = uibutton(app.UIFigure, 'push');
            app.NextActuator.ButtonPushedFcn = createCallbackFcn(app, @NextActuatorButtonPushed, true);
            app.NextActuator.Text = '+';
            app.PreviousActuator = uibutton(app.UIFigure, 'push');
            app.PreviousActuator.ButtonPushedFcn = createCallbackFcn(app, @PreviousActuatorButtonPushed, true);
            app.PreviousActuator.Text = '-';
            app.UIHwhm = uiaxes(app.UIFigure);
            app.UIXProfile = uiaxes(app.UIFigure);
            app.ProfileDropDownLabel = uilabel(app.UIFigure);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.UIFigure);
            app.ProfileDropDown.Items = {'maximum', 'gauss', 'gaussE', 'gaussER', 'lorentz', 'lorentzE'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Value = 'gauss';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UIYProfile = uiaxes(app.UIFigure);
            app.UIResidual = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ifmExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end