classdef startupGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIStartup                   matlab.ui.Figure
        PanelMenu                   matlab.ui.container.Menu
        AlignmentMenu               matlab.ui.container.Menu
        CalibrationMenu             matlab.ui.container.Menu
        EnvironmentMenu             matlab.ui.container.Menu
        EngineeringMenu             matlab.ui.container.Menu
        MirorDropDownLabel          matlab.ui.control.Label
        MirorDropDown               matlab.ui.control.DropDown
        EnvironmentStartup          matlab.ui.control.Button
        EnvironmentShutdown         matlab.ui.control.Button
        EnvironmentLampLabel        matlab.ui.control.Label
        EnvironmentLamp             matlab.ui.control.Lamp
        GimbalStartup               matlab.ui.control.Button
        GimbalShutdown              matlab.ui.control.Button
        GimbalLampLabel             matlab.ui.control.Label
        GimbalLamp                  matlab.ui.control.Lamp
        WaveFrontStartup            matlab.ui.control.Button
        WaveFrontShutdown           matlab.ui.control.Button
        WaveFrontLampLabel          matlab.ui.control.Label
        WaveFrontLamp               matlab.ui.control.Lamp
        DMStartup                   matlab.ui.control.Button
        DMShutdown                  matlab.ui.control.Button
        DMLampLabel                 matlab.ui.control.Label
        DMLamp                      matlab.ui.control.Lamp
        CloseButton                 matlab.ui.control.Button
        OuputSessionDirectoryLabel  matlab.ui.control.Label
        OutputSession               matlab.ui.control.Label
        GimbalInit                  matlab.ui.control.Button
        NewSessionButton            matlab.ui.control.Button
        Log                         matlab.ui.control.TextArea
        Button                      matlab.ui.control.Button
    end


    properties (Access = public)
        onlineColor = [0 1.0 0.0]; % Description
        offlineColor = [1.0, 0.0, 0.0]; 
        pendingColor = [0.95 0.4 0.01];
        bench;
        appTimer;
        lastLogBufferIndex = 0;
        firstLogBufferIndex = 0;
        
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            isDummy = strcmp(app.bench.config.dmId, app.bench.config.DUMMY);
            
            if app.bench.has('environment')
                app.EnvironmentLamp.Color = app.onlineColor;
                app.EnvironmentShutdown.Enable = 'on';
                app.EnvironmentStartup.Enable = 'off';
            
            else
                app.EnvironmentLamp.Color = app.offlineColor;
                app.EnvironmentShutdown.Enable = 'off';
                app.EnvironmentStartup.Enable = 'on';
            end
            
            if isDummy
                app.GimbalLamp.Color = app.offlineColor;
                app.GimbalShutdown.Enable = 'off';
                app.GimbalStartup.Enable = 'off';
                app.GimbalInit.Enable = 'off';
                app.DMLamp.Color = app.offlineColor;
                app.DMShutdown.Enable = 'off';
                app.DMStartup.Enable = 'off';
            else
                if app.bench.has('gimbal')
                    app.GimbalLamp.Color = app.onlineColor;
                    app.GimbalShutdown.Enable = 'on';
                    app.GimbalStartup.Enable = 'off';
                    app.GimbalInit.Enable = 'on';
                     

                else
                    app.GimbalLamp.Color = app.offlineColor;
                    app.GimbalShutdown.Enable = 'off';
                    app.GimbalStartup.Enable = 'on';
                    app.GimbalInit.Enable = 'off';
                end
                
                if app.bench.has('dm')
                    app.DMLamp.Color = app.onlineColor;
                    app.DMShutdown.Enable = 'on';
                    app.DMStartup.Enable = 'off';
                    

                else
                    app.DMLamp.Color = app.offlineColor;
                    app.DMShutdown.Enable = 'off';
                    app.DMStartup.Enable = 'on';
                end
                
         
            end
            
            if app.bench.has('wfs')
                    app.WaveFrontLamp.Color = app.onlineColor;
                    app.WaveFrontShutdown.Enable = 'on';
                    app.WaveFrontStartup.Enable = 'off';
                    

            else
                    app.WaveFrontLamp.Color = app.offlineColor;
                    app.WaveFrontShutdown.Enable = 'off';
                    app.WaveFrontStartup.Enable = 'on';
            end
            
            app.OutputSession.Text = app.bench.config.sessionDirectory;
            
            nNewLog =app.bench.logBuffer.index-app.lastLogBufferIndex;
            if nNewLog>0 && app.bench.logBuffer.index>0
                iStart = max([1, app.firstLogBufferIndex, app.bench.logBuffer.index-3]);
                app.Log.Value = app.bench.logBuffer.buffer(iStart:app.bench.logBuffer.index);
%                 txt = app.Log.Value;
%                 for i=1:nNewLog
%                     txt = strcat(txt, '\n', app.bench.logBuffer.buffer(app.lastLogBufferIndex+i));
%                 end
%                 
%                 app.lastLogBufferIndex = app.bench.logBuffer.index;
%                 app.Log.Value= txt;
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            global naomiGlobalBench
            if nargin<2
                if ~exist('naomiGlobalBench', 'var') || isempty(naomiGlobalBench)
                    naomiStartup;
                end
                bench = naomiGlobalBench;
            end
            app.bench = bench;
            app.lastLogBufferIndex = app.bench.logBuffer.index;
            app.firstLogBufferIndex = app.lastLogBufferIndex;
            app.appTimer = timer('Period',2.0,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.appTimer.TimerFcn = @app.refresh;
            app.MirorDropDown.Items = app.bench.config.dmIdChoices;
            if bench.config.dmId
                app.MirorDropDown.Value = app.bench.config.dmId;
            end
            %app.refresh();
            start(app.appTimer);
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.refresh;
        end

        % Button pushed function: EnvironmentStartup
        function EnvironmentStartupButtonPushed(app, event)
            app.EnvironmentLamp.Color = app.pendingColor;
            app.bench.startEnvironment();
            
            app.refresh;
        end

        % Button pushed function: EnvironmentShutdown
        function EnvironmentShutdownButtonPushed(app, event)
            app.bench.stopEnvironment();
            
            app.refresh;
        end

        % Button pushed function: GimbalStartup
        function GimbalStartupButtonPushed(app, event)
            app.GimbalLamp.Color = app.pendingColor;
            app.bench.startGimbal();
            app.refresh;
        end

        % Button pushed function: GimbalShutdown
        function GimbalShutdownButtonPushed(app, event)
            app.bench.stopGimbal();
            app.refresh;
        end

        % Button pushed function: WaveFrontStartup
        function WaveFrontStartupButtonPushed(app, event)
            app.WaveFrontLamp.Color = app.pendingColor;
            app.bench.startWfs();
            app.refresh;
        end

        % Button pushed function: WaveFrontShutdown
        function WaveFrontShutdownButtonPushed(app, event)
            app.bench.stopWfs();
            app.refresh;
        end

        % Button pushed function: DMStartup
        function DMStartupButtonPushed(app, event)
            app.DMLamp.Color = app.pendingColor;
            app.bench.startDm();
            app.refresh;
        end

        % Button pushed function: DMShutdown
        function DMShutdownButtonPushed(app, event)
            app.bench.stopDm();
            app.refresh;
        end

        % Value changed function: MirorDropDown
        function MirorDropDownValueChanged(app, event)
            value = app.MirorDropDown.Value;
            
           
            app.bench.changeDm(value);
            app.refresh;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIStartup);
        end

        % Menu selected function: AlignmentMenu
        function AlignmentMenuSelected(app, event)
            naomi.task.align(app.bench);
        end

        % Menu selected function: CalibrationMenu
        function CalibrationMenuSelected(app, event)
            naomi.task.openCalibrationPanel(app.bench);
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            app.bench.newSession;
        end

        % Button pushed function: GimbalInit
        function GimbalInitButtonPushed(app, event)
            app.bench.gimbal.init;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.refresh;
        end

        % Menu selected function: EngineeringMenu
        function EngineeringMenuSelected(app, event)
            naomi.task.openEngineeringPanel(app.bench);
        end

        % Menu selected function: EnvironmentMenu
        function EnvironmentMenuSelected(app, event)
            naomi.task.openEnvironmentPanel(app.bench);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIStartup = uifigure;
            app.UIStartup.Name = 'Naomi Startup';
            app.PanelMenu = uimenu(app.UIStartup);
            app.PanelMenu.Text = 'Panels';
            app.AlignmentMenu = uimenu(app.PanelMenu);
            app.AlignmentMenu.MenuSelectedFcn = createCallbackFcn(app, @AlignmentMenuSelected, true);
            app.AlignmentMenu.Text = 'Alignment';
            app.CalibrationMenu = uimenu(app.PanelMenu);
            app.CalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationMenuSelected, true);
            app.CalibrationMenu.Text = 'Calibration';
            app.EnvironmentMenu = uimenu(app.PanelMenu);
            app.EnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @EnvironmentMenuSelected, true);
            app.EnvironmentMenu.Text = 'Environment';
            app.EngineeringMenu = uimenu(app.PanelMenu);
            app.EngineeringMenu.MenuSelectedFcn = createCallbackFcn(app, @EngineeringMenuSelected, true);
            app.EngineeringMenu.Text = 'Engineering';
            app.MirorDropDownLabel = uilabel(app.UIStartup);
            app.MirorDropDownLabel.Text = 'Miror';
            app.MirorDropDown = uidropdown(app.UIStartup);
            app.MirorDropDown.ValueChangedFcn = createCallbackFcn(app, @MirorDropDownValueChanged, true);
            app.EnvironmentStartup = uibutton(app.UIStartup, 'push');
            app.EnvironmentStartup.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentStartupButtonPushed, true);
            app.EnvironmentStartup.Text = 'Startup';
            app.EnvironmentShutdown = uibutton(app.UIStartup, 'push');
            app.EnvironmentShutdown.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentShutdownButtonPushed, true);
            app.EnvironmentShutdown.Text = 'Shutdown';
            app.EnvironmentLampLabel = uilabel(app.UIStartup);
            app.EnvironmentLampLabel.Text = 'Environment ';
            app.EnvironmentLamp = uilamp(app.UIStartup);
            app.GimbalStartup = uibutton(app.UIStartup, 'push');
            app.GimbalStartup.ButtonPushedFcn = createCallbackFcn(app, @GimbalStartupButtonPushed, true);
            app.GimbalStartup.Text = 'Startup';
            app.GimbalShutdown = uibutton(app.UIStartup, 'push');
            app.GimbalShutdown.ButtonPushedFcn = createCallbackFcn(app, @GimbalShutdownButtonPushed, true);
            app.GimbalShutdown.Text = 'Shutdown';
            app.GimbalLampLabel = uilabel(app.UIStartup);
            app.GimbalLampLabel.Text = 'Gimbal';
            app.GimbalLamp = uilamp(app.UIStartup);
            app.WaveFrontStartup = uibutton(app.UIStartup, 'push');
            app.WaveFrontStartup.ButtonPushedFcn = createCallbackFcn(app, @WaveFrontStartupButtonPushed, true);
            app.WaveFrontStartup.Text = 'Startup';
            app.WaveFrontShutdown = uibutton(app.UIStartup, 'push');
            app.WaveFrontShutdown.ButtonPushedFcn = createCallbackFcn(app, @WaveFrontShutdownButtonPushed, true);
            app.WaveFrontShutdown.Text = 'Shutdown';
            app.WaveFrontLampLabel = uilabel(app.UIStartup);
            app.WaveFrontLampLabel.Text = 'Wave Front';
            app.WaveFrontLamp = uilamp(app.UIStartup);
            app.DMStartup = uibutton(app.UIStartup, 'push');
            app.DMStartup.ButtonPushedFcn = createCallbackFcn(app, @DMStartupButtonPushed, true);
            app.DMStartup.Text = 'Startup';
            app.DMShutdown = uibutton(app.UIStartup, 'push');
            app.DMShutdown.ButtonPushedFcn = createCallbackFcn(app, @DMShutdownButtonPushed, true);
            app.DMShutdown.Text = 'Shutdown';
            app.DMLampLabel = uilabel(app.UIStartup);
            app.DMLampLabel.Text = 'DM';
            app.DMLamp = uilamp(app.UIStartup);
            app.CloseButton = uibutton(app.UIStartup, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OuputSessionDirectoryLabel = uilabel(app.UIStartup);
            app.OuputSessionDirectoryLabel.Text = 'Ouput Session Directory :';
            app.OutputSession = uilabel(app.UIStartup);
            app.OutputSession.Text = '';
            app.GimbalInit = uibutton(app.UIStartup, 'push');
            app.GimbalInit.ButtonPushedFcn = createCallbackFcn(app, @GimbalInitButtonPushed, true);
            app.GimbalInit.Text = 'Init';
            app.NewSessionButton = uibutton(app.UIStartup, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Text = 'New Session';
            app.Log = uitextarea(app.UIStartup);
            app.Log.Editable = 'off';
            app.Button = uibutton(app.UIStartup, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '*';
        end
    end

    methods (Access = public)

        % Construct app
        function app = startupGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIStartup)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIStartup)
        end
    end
end