classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAlign           matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        TipGaugeLabel     matlab.ui.control.Label
        TipGauge          matlab.ui.control.SemicircularGauge
        TiltGaugeLabel    matlab.ui.control.Label
        TiltGauge         matlab.ui.control.SemicircularGauge
        XYLampLabel       matlab.ui.control.Label
        XYLamp            matlab.ui.control.Lamp
        TipTiltLampLabel  matlab.ui.control.Label
        TipTiltLamp       matlab.ui.control.Lamp
        XGauge            matlab.ui.control.LinearGauge
        YGauge            matlab.ui.control.LinearGauge
        TipPLus           matlab.ui.control.Button
        TipMinus          matlab.ui.control.Button
        TiltMinus         matlab.ui.control.Button
        TiltPlus          matlab.ui.control.Button
        AutoButton        matlab.ui.control.Button
    end


    properties (Access = public)
        appTimer; % timer to refresh the app
    end

    methods (Access = public)
    
        function refresh(app, t, e, f)
            global bench;
            %set(0, 'currentfigure', app.UIAlign);
            %set(app.UIAlign, 'currentAxes', app.UIAxes);
            tiltThreshold = bench.config.pupillTipTiltThresholdManual;
	    pupThreshold = bench.config.pupillCenteringThreshold;
            phi = naomi.measure.phase(bench);
            imagesc(app.UIAxes, phi);
            %imagesc(phi);
            [nX,nY] = size(phi);
            xlim(app.UIAxes, [1,nX]);
            ylim(app.UIAxes, [1,nY]);
            %plot(app.UIAxes, [1,2,3,4,3,6]);
            [dX,dY,dTip,dTilt,dFoc] = naomi.measure.missalignment(bench, phi);
            title(app.UIAxes,{ datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF'),
               sprintf('x=%.3fmm  y=%.3fmm  tip=%.2f  tilt=%.2f',...
                       dX*1e3, dY*1e3, dTip, dTilt)
               });

           app.TipGauge.Value = dTip;
           app.TiltGauge.Value = dTilt;
           app.XGauge.Value = dX*1000;
           app.YGauge.Value = dY*1000;
           %if ((dX^2+dY^2)<= pupThreshold^2)
           if ((abs(dX)<= pupThreshold) && (abs(dY)<= pupThreshold))
               app.XYLamp.Color = 'green';
           else
               app.XYLamp.Color = 'red';
           end
           %if ((dTip^2+dTilt^2) <= tiltThreshold^2)
           if (abs(dTip) <= tiltThreshold) && (abs(dTilt) <= tiltThreshold)
               app.TipTiltLamp.Color = 'green';
           else
               app.TipTiltLamp.Color = 'red';
           end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global bench
            app.appTimer = timer('Period',0.7,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.appTimer.TimerFcn = @app.refresh;
            start(app.appTimer);
            
            tiltThreshold = bench.config.pupillTipTiltThresholdManual;
	    pupThreshold = bench.config.pupillCenteringThreshold*1000;
            
            app.TipGauge.ScaleColors = {'red', 'green', 'red'};
            app.TipGauge.ScaleColorLimits = [-10 -tiltThreshold ; -tiltThreshold tiltThreshold ; tiltThreshold 10];
            app.TiltGauge.ScaleColors = {'red', 'green', 'red'};
            app.TiltGauge.ScaleColorLimits = [-10 -tiltThreshold ; -tiltThreshold tiltThreshold ; tiltThreshold 10];
            
            app.XGauge.ScaleColors = {'red', 'green', 'red'};
            app.XGauge.ScaleColorLimits = [-10 -pupThreshold ; -pupThreshold pupThreshold ; pupThreshold 10];
            app.YGauge.ScaleColors = {'red', 'green', 'red'};
            app.YGauge.ScaleColorLimits = [-10 -pupThreshold ; -pupThreshold pupThreshold ; pupThreshold 10];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAlign = uifigure;
            app.UIAlign.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIAlign);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TipGaugeLabel = uilabel(app.UIAlign);
            app.TipGaugeLabel.Text = 'Tip';
            app.TipGauge = uigauge(app.UIAlign, 'semicircular');
            app.TipGauge.Limits = [-3 3];
            app.TiltGaugeLabel = uilabel(app.UIAlign);
            app.TiltGaugeLabel.Text = 'Tilt';
            app.TiltGauge = uigauge(app.UIAlign, 'semicircular');
            app.TiltGauge.Limits = [-3 3];
            app.XYLampLabel = uilabel(app.UIAlign);
            app.XYLampLabel.Text = 'X/Y';
            app.XYLamp = uilamp(app.UIAlign);
            app.TipTiltLampLabel = uilabel(app.UIAlign);
            app.TipTiltLampLabel.Text = 'TipTilt';
            app.TipTiltLamp = uilamp(app.UIAlign);
            app.XGauge = uigauge(app.UIAlign, 'linear');
            app.XGauge.Limits = [-0.25 0.25];
            app.YGauge = uigauge(app.UIAlign, 'linear');
            app.YGauge.Limits = [-0.25 0.25];
            app.TipPLus = uibutton(app.UIAlign, 'push');
            app.TipPLus.Text = '+';
            app.TipMinus = uibutton(app.UIAlign, 'push');
            app.TipMinus.Text = '-';
            app.TiltMinus = uibutton(app.UIAlign, 'push');
            app.TiltMinus.Text = '-';
            app.TiltPlus = uibutton(app.UIAlign, 'push');
            app.TiltPlus.Text = '+';
            app.AutoButton = uibutton(app.UIAlign, 'push');
            app.AutoButton.Text = 'Auto';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testgui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIAlign)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAlign)
        end
    end
end