classdef ztcExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIztc                    matlab.ui.control.UIAxes
        ZernikeEditFieldLabel    matlab.ui.control.Label
        ZernikeNumber            matlab.ui.control.NumericEditField
        PreviousZernike          matlab.ui.control.Button
        NextZernike              matlab.ui.control.Button
        UIdm                     matlab.ui.control.UIAxes
        UITheoriticalPhase       matlab.ui.control.UIAxes
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        Amplitude                matlab.ui.control.NumericEditField
        UIZernikeInfo            matlab.ui.control.UIAxes
        CloseButton              matlab.ui.control.Button
    end


    properties (Access = public)
        ZtCData; % The ZtC Matrix as naomi.data.ZtC
        BiasData; % TheDM bias as naomi.data.dmVector
        curentZernike = 1;
    end

    methods (Access = public)
    
        function refresh(app)
            app.ZernikeNumber.Value = app.curentZernike;
            
            app.ZtCData.plot(app.UIztc);
            
            dmCommandData = app.ZtCData.dmVector(app.curentZernike, app.Amplitude.Value);
            dmCommandData.plot(app.UIdm);
            
            theoriticalPhase = naomi.compute.theoriticalPhase(512, 512/2, 512/2, 512, (app.curentZernike));
            imagesc(app.UITheoriticalPhase, theoriticalPhase);
            xlim(app.UITheoriticalPhase, [0,512]);
            ylim(app.UITheoriticalPhase, [0,512]);
            
            [~, longName, equationStr] = naomi.compute.zernikeInfo(app.curentZernike);
            cla(app.UIZernikeInfo);
            box(app.UIZernikeInfo,'off');
            set(app.UIZernikeInfo,'Visible','off');
            text(app.UIZernikeInfo, ...
                 0.5, 0.5, {longName, '', strcat('$',equationStr,'$')}, ...
                 'Interpreter', 'latex',...
                 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle',...
                 'FontSize',18);
             
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ZtCData)
            app.ZtCData = ZtCData;
            
            [nZernike,~] = size(app.ZtCData.data);
            app.ZernikeNumber.Limits = [1,nZernike];
            app.refresh();
            app.ZernikeNumber.Value = app.curentZernike;
            
            box(app.UITheoriticalPhase,'off');
            set(app.UITheoriticalPhase,'xtick',[]);
            set(app.UITheoriticalPhase,'xticklabel',[]);
            set(app.UITheoriticalPhase,'ytick',[]);
            set(app.UITheoriticalPhase,'yticklabel',[]);
            daspect(app.UITheoriticalPhase, [1 1 1]);
            
            
            box(app.UIZernikeInfo,'off');
            set(app.UIZernikeInfo,'xtick',[]);
            set(app.UIZernikeInfo,'xticklabel',[]);
            set(app.UIZernikeInfo,'ytick',[]);
            set(app.UIZernikeInfo,'yticklabel',[]);
            daspect(app.UIZernikeInfo, [1 1 1]);
        end

        % Value changed function: ZernikeNumber
        function ZernikeNumberValueChanged(app, event)
            value = app.ZernikeNumber.Value;
            app.curentZernike = value;
            app.refresh();
        end

        % Button pushed function: PreviousZernike
        function PreviousZernikeButtonPushed(app, event)
            l = app.ZernikeNumber.Limits;
            if app.curentZernike>l(1);
                app.curentZernike = app.curentZernike -1;
                app.refresh();
            end
        end

        % Button pushed function: NextZernike
        function NextZernikeButtonPushed(app, event)
            l = app.ZernikeNumber.Limits;
            
            if app.curentZernike<l(2);
                app.curentZernike = app.curentZernike +1;
                app.refresh();
            end
        end

        % Value changed function: Amplitude
        function AmplitudeValueChanged(app, event)

            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIztc = uiaxes(app.UIFigure);
            app.ZernikeEditFieldLabel = uilabel(app.UIFigure);
            app.ZernikeEditFieldLabel.Text = 'Zernike';
            app.ZernikeNumber = uieditfield(app.UIFigure, 'numeric');
            app.ZernikeNumber.ValueDisplayFormat = '%.0f';
            app.ZernikeNumber.ValueChangedFcn = createCallbackFcn(app, @ZernikeNumberValueChanged, true);
            app.ZernikeNumber.Value = 1;
            app.PreviousZernike = uibutton(app.UIFigure, 'push');
            app.PreviousZernike.ButtonPushedFcn = createCallbackFcn(app, @PreviousZernikeButtonPushed, true);
            app.PreviousZernike.Text = '-';
            app.NextZernike = uibutton(app.UIFigure, 'push');
            app.NextZernike.ButtonPushedFcn = createCallbackFcn(app, @NextZernikeButtonPushed, true);
            app.NextZernike.Text = '+';
            app.UIdm = uiaxes(app.UIFigure);
            app.UITheoriticalPhase = uiaxes(app.UIFigure);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.UIFigure, 'numeric');
            app.Amplitude.ValueChangedFcn = createCallbackFcn(app, @AmplitudeValueChanged, true);
            app.Amplitude.Value = 1;
            app.UIZernikeInfo = uiaxes(app.UIFigure);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ztcExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end